classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        NumberOfRolls    matlab.ui.control.TextArea
        TotalPanel       matlab.ui.control.TextArea
        Die2Panel        matlab.ui.control.TextArea
        Die1Panel        matlab.ui.control.TextArea
        TextArea_2Label  matlab.ui.control.Label
        ROLLButton       matlab.ui.control.StateButton
        TextArea         matlab.ui.control.TextArea
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numRolls = 0; % Description
        dieTotalHistory = zeros(11,1) % Description
        possibleDieTotal = [2:12] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function Die1PanelValueChanged(app, event)
            
        end

        % Value changed function: ROLLButton
        function ROLLButtonValueChanged(app, event)
           die1 = randi([1 6]);
die2 = randi([1 6]);
rollTotal = die1 + die2;
app.numRolls = app.numRolls + 1;


        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            app.Roll1TextArea.Value = num2str(die1);
app.Roll2TextArea.Value = num2str(die2);
app.RollTotalTextArea.Value = num2str(rollTotal);
app.NumberOfRollsTextArea.Value = num2str(app.numRolls);
            
app.dieTotalHistory(rollTotal-1) = app.dieTotalHistory(rollTotal-1) + 1;
            
bar(app.UIAxes, app.possibleDieTotal, app.dieTotalHistory);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Roll Results')
            xlabel(app.UIAxes, 'Roll Total')
            ylabel(app.UIAxes, 'Frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'G-Vegas Simulator '};
            app.ROLLButton = uibutton(app.UIFigure, 'state');
            app.ROLLButton.ValueChangedFcn = createCallbackFcn(app, @ROLLButtonValueChanged, true);
            app.ROLLButton.Text = 'ROLL';
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Text Area';
            app.Die1Panel = uitextarea(app.UIFigure);
            app.Die2Panel = uitextarea(app.UIFigure);
            app.TotalPanel = uitextarea(app.UIFigure);
            app.NumberOfRolls = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end